classdef app2 < matlab.apps.AppBase

    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        LeftGrid          matlab.ui.container.GridLayout
        % Mode
        ModeDropDownLabel matlab.ui.control.Label
        ModeDropDown      matlab.ui.control.DropDown
        % Source bits
        NEditFieldLabel   matlab.ui.control.Label
        NEditField        matlab.ui.control.NumericEditField
        % Modulation
        ModDropDownLabel  matlab.ui.control.Label
        ModDropDown       matlab.ui.control.DropDown
        % SNR start
        SNRStartLabel     matlab.ui.control.Label
        SNRStartEdit      matlab.ui.control.NumericEditField
        % SNR end
        SNREndLabel       matlab.ui.control.Label
        SNREndEdit        matlab.ui.control.NumericEditField
        % SNR step
        SNRStepLabel      matlab.ui.control.Label
        SNRStepEdit       matlab.ui.control.NumericEditField
        % CFO
        CFOLabel          matlab.ui.control.Label
        CFOEdit           matlab.ui.control.NumericEditField
        % Equalizer
        EqDropDownLabel   matlab.ui.control.Label
        EqDropDown        matlab.ui.control.DropDown
        % Decision
        DecDropDownLabel  matlab.ui.control.Label
        DecDropDown       matlab.ui.control.DropDown
        % Pilot spacing
        PilotLabel        matlab.ui.control.Label
        PilotEdit         matlab.ui.control.NumericEditField
        % Buttons
        RunButton         matlab.ui.control.Button
        FullVizButton     matlab.ui.control.Button
        StatusLabel       matlab.ui.control.Label
        % Right
        RightPanel        matlab.ui.container.Panel
        RightGrid         matlab.ui.container.GridLayout
        TabGroup          matlab.ui.container.TabGroup
        TabBER            matlab.ui.container.Tab
        TabConst          matlab.ui.container.Tab
        % BER sub-axes
        BERGrid           matlab.ui.container.GridLayout
        AxBER             matlab.ui.control.UIAxes
        AxFER             matlab.ui.control.UIAxes
        % Constellation sub-axes
        ConstGrid         matlab.ui.container.GridLayout
        AxConst           matlab.ui.control.UIAxes
    end

    properties (Access = private)
        onePanelWidth = 576;
    end

    methods (Access = private)
        function updateAppLayout(app, event)
            w = app.UIFigure.Position(3);
            if w <= app.onePanelWidth
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        function runSimulation(app)
            app.StatusLabel.Text = 'Running...'; drawnow;

            use_mimo = strcmp(app.ModeDropDown.Value, '2x2 MIMO');
            N     = app.NEditField.Value;
            mod_t = app.ModDropDown.Value;
            snr_v = app.SNRStartEdit.Value : app.SNRStepEdit.Value : app.SNREndEdit.Value;
            cfo   = app.CFOEdit.Value;
            eq_m  = app.EqDropDown.Value;
            dec_t = app.DecDropDown.Value;
            p_sp  = app.PilotEdit.Value;
            f_len = 1024;
            n_snr = length(snr_v);
            ber_v = zeros(1, n_snr);
            fer_v = zeros(1, n_snr);

            bits = generate_source(N);
            tx_b = crc16_encode(bits, f_len);
            coded = channel_encode(tx_b);
            [int_b, perm] = interleave(coded);

            if use_mimo
                [tx1, tx2, cfg] = mimo_ofdm_assemble_frame(int_b, mod_t, p_sp);
            else
                [tx_f, cfg] = ofdm_assemble_frame(int_b, mod_t, p_sp);
            end

            for i = 1:n_snr
                snr_db = snr_v(i);
                app.StatusLabel.Text = sprintf('SNR %.0f dB (%d/%d)...', snr_db, i, n_snr); drawnow;

                if use_mimo
                    [rx1, rx2] = mimo_channel(tx1, tx2);
                    fs_rx = 1e6;  rx_p = (mean(abs(rx1).^2)+mean(abs(rx2).^2))/2;
                    if cfo~=0
                        t1=(0:length(rx1)-1)'/fs_rx; t2=(0:length(rx2)-1)'/fs_rx;
                        rx1=rx1.*exp(1j*2*pi*cfo*t1); rx2=rx2.*exp(1j*2*pi*cfo*t2);
                    end
                    rx1=add_awgn(rx1,snr_db); rx2=add_awgn(rx2,snr_db);
                    [rx1s,rx2s,fs1,~]=ofdm_mimo_sync(rx1,rx2,cfg);
                    Hd=mimo_channel_est(rx1s,rx2s,cfg,fs1);
                    n_p=cfg.n_data_pairs; dp=cfg.data_sym_pos; Xa=[];
                    for p=1:n_p
                        d1=2*p-1; d2=2*p;
                        s1=fs1+dp(d1)*cfg.sym_len; s2=fs1+dp(d2)*cfg.sym_len;
                        if s2+cfg.sym_len-1>min(length(rx1s),length(rx2s)), break; end
                        Y1t1=ofdm_rx_demod(rx1s(s1:s1+cfg.sym_len-1),cfg);
                        Y1t2=ofdm_rx_demod(rx1s(s2:s2+cfg.sym_len-1),cfg);
                        Y2t1=ofdm_rx_demod(rx2s(s1:s1+cfg.sym_len-1),cfg);
                        Y2t2=ofdm_rx_demod(rx2s(s2:s2+cfg.sym_len-1),cfg);
                        [sh1,sh2]=alamouti_decode(Y1t1,Y1t2,Y2t1,Y2t2,Hd(d1));
                        Xa=[Xa;sh1;sh2];
                    end
                    Xa=Xa(1:ceil(length(int_b)/app_bpsym(mod_t)));
                else
                    [rx_s, ~] = vhf_channel(tx_f);
                    fs_rx=1e6; rx_p=mean(abs(rx_s).^2);
                    if cfo~=0, tv=(0:length(rx_s)-1)'/fs_rx; rx_s=rx_s.*exp(1j*2*pi*cfo*tv); end
                    rx_s=add_awgn(rx_s,snr_db);
                    fs_s=ofdm_time_sync(rx_s,cfg);
                    [rx_sync,~]=ofdm_freq_sync(rx_s,cfg,fs_s);
                    Hd=channel_estimate_interp(rx_sync,cfg,fs_s,snr_db);
                    nd=cfg.n_data_syms; dp=cfg.data_sym_pos;
                    s2_eq=rx_p*10^(-snr_db/10); Xa=[];
                    for s=1:nd
                        ss=fs_s+dp(s)*cfg.sym_len;
                        if ss+cfg.sym_len-1>length(rx_sync), break; end
                        Ys=ofdm_rx_demod(rx_sync(ss:ss+cfg.sym_len-1),cfg);
                        Xa=[Xa;channel_equalize(Ys,Hd(:,s),eq_m,s2_eq)];
                    end
                    Xa=Xa(1:ceil(length(int_b)/app_bpsym(mod_t)));
                end

                if strcmp(dec_t,'Soft')
                    llr=demodulate(Xa,mod_t,'soft'); llrd=deinterleave(llr,perm);
                    llrd=llrd(1:length(coded)); rx_dec=channel_decode(llrd,'soft');
                else
                    bh=demodulate(Xa,mod_t,'hard'); bhd=deinterleave(bh,perm);
                    bhd=bhd(1:length(coded)); rx_dec=channel_decode(bhd,'hard');
                end
                [~,ber_v(i),fer_v(i)]=crc16_check(rx_dec,bits,f_len);
            end

            % Plot BER
            semilogy(app.AxBER, snr_v, ber_v, 'bo-', 'LineWidth', 1.5, 'MarkerSize', 8);
            xlabel(app.AxBER, 'SNR (dB)'); ylabel(app.AxBER, 'BER');
            xlim(app.AxBER, [snr_v(1) snr_v(end)]); xticks(app.AxBER, snr_v);
            title(app.AxBER, sprintf('BER — %s %s %s %s', app.ModeDropDown.Value, mod_t, eq_m, dec_t));
            grid(app.AxBER, 'on');

            semilogy(app.AxFER, snr_v, fer_v, 'rs-', 'LineWidth', 1.5, 'MarkerSize', 8);
            xlabel(app.AxFER, 'SNR (dB)'); ylabel(app.AxFER, 'FER');
            xlim(app.AxFER, [snr_v(1) snr_v(end)]); xticks(app.AxFER, snr_v);
            title(app.AxFER, 'FER');
            grid(app.AxFER, 'on');

            % Constellation
            Np = min(2000, length(Xa));
            plot(app.AxConst, real(Xa(1:Np)), imag(Xa(1:Np)), 'b.', 'MarkerSize', 5);
            hold(app.AxConst, 'on');
            ref = [1+1j,1-1j,-1+1j,-1-1j]/sqrt(2);
            plot(app.AxConst, real(ref), imag(ref), 'ko', 'MarkerSize', 12, 'LineWidth', 2);
            hold(app.AxConst, 'off');
            axis(app.AxConst, 'equal'); grid(app.AxConst, 'on');
            legend(app.AxConst, 'Output', 'Ideal');
            title(app.AxConst, sprintf('Constellation — SNR=%.0f dB', snr_v(round(n_snr/2))));

            app.StatusLabel.Text = sprintf('Done. Best BER=%.2e', min(ber_v));
        end

        function runFullViz(app)
            app.StatusLabel.Text = 'Running full test...'; drawnow;
            use_mimo = strcmp(app.ModeDropDown.Value, '2x2 MIMO');
            close all;
            if use_mimo, run_test_wrapper(true); fig_nums=[1 2 3];
            else,        run_test_wrapper(false); fig_nums=[1 2 3 4 5 6 7 8];
            end
            for k=1:length(fig_nums)
                fn=fig_nums(k); if ~ishandle(fn), continue; end
                fig=figure(fn); fig.Visible='on'; drawnow;
                frame=getframe(fig); img=frame.cdata; close(fig);
                t=uitab(app.TabGroup,'Title',sprintf('Fig %d',fn));
                g=uigridlayout(t,[1 1]); g.Padding=[0 0 0 0];
                ax=uiaxes(g); ax.Layout.Row=1; ax.Layout.Column=1;
                imshow(img,'Parent',ax); ax.Visible='off';
            end
            app.StatusLabel.Text = 'Full viz complete.';
        end
    end

    methods (Access = private)
        function createComponents(app)
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            app.UIFigure = uifigure('Visible','off');
            app.UIFigure.Name = 'VHF OFDM Simulator — Group 2';
            app.UIFigure.SizeChangedFcn = @(~,~) app.updateAppLayout();
            app.GridLayout = uigridlayout(app.UIFigure);
            % Left panel
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftGrid = uigridlayout(app.LeftPanel, [18 2]);
            r = 1;
            app.ModeDropDownLabel = uilabel(app.LeftGrid, 'Text','Antenna Mode:','HorizontalAlignment','right');
            app.ModeDropDown = uidropdown(app.LeftGrid, 'Items',{'SISO','2x2 MIMO'},'Value','SISO');
            app.NEditFieldLabel = uilabel(app.LeftGrid, 'Text','Source Bits:','HorizontalAlignment','right');
            app.NEditField = uieditfield(app.LeftGrid,'numeric','Value',10000,'Limits',[1000 1e7]);
            app.ModDropDownLabel = uilabel(app.LeftGrid, 'Text','Modulation:','HorizontalAlignment','right');
            app.ModDropDown = uidropdown(app.LeftGrid, 'Items',{'QPSK','16QAM','64QAM'},'Value','QPSK');
            app.SNRStartLabel = uilabel(app.LeftGrid, 'Text','SNR Start (dB):','HorizontalAlignment','right');
            app.SNRStartEdit = uieditfield(app.LeftGrid,'numeric','Value',0,'Limits',[-10 40]);
            app.SNREndLabel = uilabel(app.LeftGrid, 'Text','SNR End (dB):','HorizontalAlignment','right');
            app.SNREndEdit = uieditfield(app.LeftGrid,'numeric','Value',25,'Limits',[-10 40]);
            app.SNRStepLabel = uilabel(app.LeftGrid, 'Text','SNR Step (dB):','HorizontalAlignment','right');
            app.SNRStepEdit = uieditfield(app.LeftGrid,'numeric','Value',5,'Limits',[0.5 10]);
            app.CFOLabel = uilabel(app.LeftGrid, 'Text','CFO (Hz):','HorizontalAlignment','right');
            app.CFOEdit = uieditfield(app.LeftGrid,'numeric','Value',10,'Limits',[0 100]);
            app.EqDropDownLabel = uilabel(app.LeftGrid, 'Text','Equalizer:','HorizontalAlignment','right');
            app.EqDropDown = uidropdown(app.LeftGrid, 'Items',{'MMSE','ZF'},'Value','MMSE');
            app.DecDropDownLabel = uilabel(app.LeftGrid, 'Text','Decision:','HorizontalAlignment','right');
            app.DecDropDown = uidropdown(app.LeftGrid, 'Items',{'Soft','Hard'},'Value','Soft');
            app.PilotLabel = uilabel(app.LeftGrid, 'Text','Pilot Spacing:','HorizontalAlignment','right');
            app.PilotEdit = uieditfield(app.LeftGrid,'numeric','Value',40,'Limits',[10 200]);
            r=r+1;
            app.RunButton = uibutton(app.LeftGrid,'push','Text','▶ Run SNR Sweep',...
                'FontWeight','bold','BackgroundColor',[0.23 0.51 0.92],'FontColor','white');
            app.RunButton.ButtonPushedFcn = @(~,~) app.runSimulation();
            app.FullVizButton = uibutton(app.LeftGrid,'push','Text','▶ Full Visualization',...
                'FontWeight','bold','BackgroundColor',[0.85 0.33 0.10],'FontColor','white');
            app.FullVizButton.ButtonPushedFcn = @(~,~) app.runFullViz();
            r=r+1;
            app.StatusLabel = uilabel(app.LeftGrid, 'Text','Ready.','FontColor',[0.4 0.4 0.4]);
            % Right panel
            app.RightPanel = uipanel(app.GridLayout);
            app.RightGrid = uigridlayout(app.RightPanel, [1 1]);
            app.TabGroup = uitabgroup(app.RightGrid);
            % Tab: BER Curve
            app.TabBER = uitab(app.TabGroup, 'Title', 'BER Curve');
            app.BERGrid = uigridlayout(app.TabBER, [2 1]);
            app.AxBER = uiaxes(app.BERGrid);
            app.AxFER = uiaxes(app.BERGrid);
            title(app.AxBER, 'BER'); title(app.AxFER, 'FER');
            % Tab: Constellation
            app.TabConst = uitab(app.TabGroup, 'Title', 'Constellation');
            app.ConstGrid = uigridlayout(app.TabConst, [1 1]);
            app.AxConst = uiaxes(app.ConstGrid);
            title(app.AxConst, 'Constellation');
            app.UIFigure.Visible = 'on';
        end
    end

    methods (Access = public)
        function app = app2
            createComponents(app)
            registerApp(app, app.UIFigure)
            if nargout == 0, clear app; end
        end
        function delete(app)
            delete(app.UIFigure)
        end
    end
end

function b = app_bpsym(mod_type)
    switch upper(mod_type)
        case 'QPSK',  b = 2;
        case '16QAM', b = 4;
        case '64QAM', b = 6;
        otherwise,    b = 2;
    end
end